classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectFolderButton         matlab.ui.control.Button
        Information                matlab.ui.control.Label
        Im0                        matlab.ui.control.Image
        Im1                        matlab.ui.control.Image
        ComputeDisparityMapButton  matlab.ui.control.Button
        PlotDisparityMap           matlab.ui.control.UIAxes
        ShowR                      matlab.ui.control.Table
        ShowT                      matlab.ui.control.Table
        RMatrixLabel               matlab.ui.control.Label
        TMatrixLabel               matlab.ui.control.Label
        PLabel                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        scene_path % The scenepath is the path to a folder containing a scene
        D % Computed disparity map
        R % Computed rotation matrix
        T % Computed translation matrix
        p % PSNR in dB
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.scene_path = uigetdir;
            figure(app.UIFigure);
            if exist(string([app.scene_path, '\Im0.png']), "file")
                app.Im0.ImageSource = string([app.scene_path, '\Im0.png']);
                app.Im1.ImageSource = string([app.scene_path, '\Im1.png']);
                app.Information.Text = app.scene_path;
                app.Im0.Visible = true;
                app.Im1.Visible = true;
                app.ComputeDisparityMapButton.Visible = true;
            else
                app.Information.Text = 'Please select a scene folder with two imgaes.';
            end
        end

        % Button pushed function: ComputeDisparityMapButton
        function ComputeDisparityMapButtonPushed(app, event)
%             [app.D, app.R, app.T] = disparity_map(app.scene_path);
%             app.p = verify_dmap(D, G)
            app.D = readpfm("disp0.pfm");
            app.R = ones(3);
            app.T = zeros(3);
            app.p = 0.5;
            
            % Show disparity map
            app.D = app.D/256;
            imshow(app.D, 'Parent',app.PlotDisparityMap)
            app.PlotDisparityMap.Colormap = colormap('jet');
            
            % Show rotation matrix
            app.ShowR.ColumnName = [];
            data = table(app.R);
            app.ShowR.Data = data;
            app.ShowR.ColumnWidth = 'auto';
            app.RMatrixLabel.Visible = true;
            app.ShowR.Visible = true;
            
            % Show translation matrix
            app.ShowT.ColumnName = [];
            data = table(app.T);
            app.ShowT.Data = data;
            app.ShowT.ColumnWidth = 'auto';
            app.TMatrixLabel.Visible = true;
            app.ShowT.Visible = true;
            
            % Show p label
            app.PLabel.Text = sprintf('p = %f', app.p);
            app.PLabel.Visible = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.Information = uilabel(app.UIFigure);
            app.Information.Enable = 'off';
            app.Information.Text = '';
            app.Im0 = uiimage(app.UIFigure);
            app.Im0.Visible = 'off';
            app.Im1 = uiimage(app.UIFigure);
            app.Im1.Visible = 'off';
            app.ComputeDisparityMapButton = uibutton(app.UIFigure, 'push');
            app.ComputeDisparityMapButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeDisparityMapButtonPushed, true);
            app.ComputeDisparityMapButton.Visible = 'off';
            app.ComputeDisparityMapButton.Text = 'Compute Disparity Map';
            app.PlotDisparityMap = uiaxes(app.UIFigure);
            title(app.PlotDisparityMap, 'Disparity Map')
            xlabel(app.PlotDisparityMap, '')
            ylabel(app.PlotDisparityMap, '')
            app.PlotDisparityMap.Visible = 'off';
            app.ShowR = uitable(app.UIFigure);
            app.ShowR.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ShowR.RowName = {};
            app.ShowR.Visible = 'off';
            app.ShowT = uitable(app.UIFigure);
            app.ShowT.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ShowT.RowName = {};
            app.ShowT.Visible = 'off';
            app.RMatrixLabel = uilabel(app.UIFigure);
            app.RMatrixLabel.Enable = 'off';
            app.RMatrixLabel.Visible = 'off';
            app.RMatrixLabel.Text = 'R Matrix:';
            app.TMatrixLabel = uilabel(app.UIFigure);
            app.TMatrixLabel.Enable = 'off';
            app.TMatrixLabel.Visible = 'off';
            app.TMatrixLabel.Text = 'T Matrix:';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Enable = 'off';
            app.PLabel.Visible = 'off';
            app.PLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end